classdef app_bancada < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DateEditField                 matlab.ui.control.EditField
        DateEditFieldLabel            matlab.ui.control.Label
        Est_riegoLamp                 matlab.ui.control.Lamp
        Est_riegoLampLabel            matlab.ui.control.Label
        RiegoSwitch_2                 matlab.ui.control.ToggleSwitch
        RiegoSwitch_2Label_2          matlab.ui.control.Label
        ParadaEmergenciaButton        matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        HumedadesTab                  matlab.ui.container.Tab
        horasLabel                    matlab.ui.control.Label
        TimeEditField                 matlab.ui.control.NumericEditField
        TimeEditFieldLabel            matlab.ui.control.Label
        GuardarImagenButton           matlab.ui.control.Button
        ExportarCSVButton             matlab.ui.control.Button
        UIAxes_Humedades              matlab.ui.control.UIAxes
        UIAxes_EV                     matlab.ui.control.UIAxes
        ControladorTab                matlab.ui.container.Tab
        TsEditField                   matlab.ui.control.NumericEditField
        TsEditFieldLabel              matlab.ui.control.Label
        InicioButton                  matlab.ui.control.Button
        LimiteinferiorEditField       matlab.ui.control.NumericEditField
        LimiteinferiorEditFieldLabel  matlab.ui.control.Label
        LimitesuperiorEditField       matlab.ui.control.NumericEditField
        LimitesuperiorEditFieldLabel  matlab.ui.control.Label
        ControladorDropDown           matlab.ui.control.DropDown
        ControladorDropDownLabel      matlab.ui.control.Label
        SelectDatosTab                matlab.ui.container.Tab
        GuardarImagenButton_2         matlab.ui.control.Button
        ExportarCSVButton_2           matlab.ui.control.Button
        FechafinalEditField           matlab.ui.control.EditField
        FechafinalEditFieldLabel      matlab.ui.control.Label
        FechainitialEditField         matlab.ui.control.EditField
        FechainitialEditFieldLabel    matlab.ui.control.Label
        TipodataDropDown              matlab.ui.control.DropDown
        TipodataDropDownLabel         matlab.ui.control.Label
        SelectionarButton             matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        LogTab                        matlab.ui.container.Tab
        LogEventos                    matlab.ui.control.TextArea
        logEventosLabel               matlab.ui.control.Label
        MedicionesPanel               matlab.ui.container.Panel
        kmhLabel                      matlab.ui.control.Label
        CLabel_2                      matlab.ui.control.Label
        CLabel                        matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        Temperatura1EditField         matlab.ui.control.NumericEditField
        Temperatura1EditFieldLabel    matlab.ui.control.Label
        PesoglobalEditField           matlab.ui.control.NumericEditField
        PesoglobalEditFieldLabel      matlab.ui.control.Label
        Temperatura2EditField         matlab.ui.control.NumericEditField
        Temperatura2EditFieldLabel    matlab.ui.control.Label
        VientoEditField               matlab.ui.control.NumericEditField
        VientoEditFieldLabel          matlab.ui.control.Label
        RadiacionEditField            matlab.ui.control.NumericEditField
        RadiacionEditFieldLabel       matlab.ui.control.Label
        Humedad2EditField             matlab.ui.control.NumericEditField
        Humedad2EditFieldLabel        matlab.ui.control.Label
        Humedad1EditField             matlab.ui.control.NumericEditField
        Humedad1EditFieldLabel        matlab.ui.control.Label
        VentiladorPanel               matlab.ui.container.Panel
        EntradaSlider                 matlab.ui.control.Slider
        EntradaSliderLabel            matlab.ui.control.Label
        VisualizadorGauge             matlab.ui.control.SemicircularGauge
        VisualizadorGaugeLabel        matlab.ui.control.Label
        PLCPanel                      matlab.ui.container.Panel
        ConectarPLCButton             matlab.ui.control.Button
        IPEditField                   matlab.ui.control.EditField
        IPEditFieldLabel              matlab.ui.control.Label
        EstadoPLCLamp                 matlab.ui.control.Lamp
        EstadoPLCLampLabel            matlab.ui.control.Label
        LucesPanel                    matlab.ui.container.Panel
        Lamp_1Switch_5                matlab.ui.control.ToggleSwitch
        Lamp_1Switch_4                matlab.ui.control.ToggleSwitch
        Lamp_1Switch_2                matlab.ui.control.ToggleSwitch
        Lamp_1Switch                  matlab.ui.control.ToggleSwitch
        Lamp4                         matlab.ui.control.Lamp
        Lamp4Label                    matlab.ui.control.Label
        Lamp3                         matlab.ui.control.Lamp
        Lamp3Label                    matlab.ui.control.Label
        Lamp2                         matlab.ui.control.Lamp
        Lamp2Label                    matlab.ui.control.Label
        Lamp1                         matlab.ui.control.Lamp
        Lamp1Label                    matlab.ui.control.Label
        MododefuncionamientoPanel     matlab.ui.container.Panel
        VentiladorSwitch              matlab.ui.control.ToggleSwitch
        VentiladorSwitchLabel         matlab.ui.control.Label
        RiegoSwitch                   matlab.ui.control.ToggleSwitch
        RiegoSwitch_2Label            matlab.ui.control.Label
        LucesSwitch                   matlab.ui.control.ToggleSwitch
        LucesSwitchLabel              matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        logEventos string = "" % Description
        timerSensores % Description
        bufferHumedad1 double = zeros(1,10)
        bufferHumedad2 double = zeros(1,10)
        bufferLimSup double = zeros(1,10)
        bufferLimInf double = zeros(1,10)
        bufferTime double = zeros(1,10)% Description
        bufferEV double = zeros(1,10);  % Buffer para estado de la electroválvula
        plcObj % Description
        registroDatos cell = {}; % Cada fila: {timestamp, sensor1, sensor2, ... , evento}
        tiempoUltimoGuardado datetime = datetime('now'); % Marca de tiempo del último guardado periódico
        intervaloGuardado double = 10; % Segundos (ajustable por el usuario)
        timerGuardado % Description
        lim_sup % Description
        lim_inf
        timer_controlador % Description
        humedad1 % Description
        humedad2
        temp1
        temp2
        evState % Description
%         currentDate datetime = datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss') % Hora
        currentDate
        sqlquery % Description
        viento % Description
        carga1
        carga2
        carga3
        carga4
        volt_viento
        radiacion
        lampStates_1
        lampStates_2
        lampStates_3
        lampStates_4
        timerBaseDeDatos % Description
        conn % Description
        pesoGlobal
        fecha1
        fecha2
        SqlSelectDatos
        tipo
        archivoCSV % Description
        h1 % Description
        h2
        hLimSup
        hLimInf
        hEV
        fechas
        valeurs
    end
    
    methods (Access = private)
        
        function logEvento(app, mensaje)
            % Genera la fecha y hora actual
            fecha = datestr(now, 'yyyy-mm-dd HH:MM:SS');
            % Prepara la línea a mostrar
            linea = sprintf("%s: %s", fecha, mensaje);
            % Añade al principio del log (opcional: para mostrar eventos recientes arriba)
            app.logEventos = [linea; app.logEventos];
            % Actualiza el Text Area
            app.LogEventos.Value = app.logEventos;
        end
        
        function leerSensores(app)
            try
                % leemos el voltage del controlador del ventilador
                app.currentDate = datestr(now, 'yyyy-mm-dd HH:MM:SS'); % Hora
                app.volt_viento = read(app.plcObj, 'holdingregs', 208, 1);
                app.volt_viento = app.volt_viento/1000;

                app.viento = read(app.plcObj, 'holdingregs', 205, 1);
                %                 a_corriente = (20-(786428/65535))/32767;
                %                 b_corriente = 786428/65535;
                %                 corriente_calibrado = a_corriente* viento + b_corriente;
                %                 a = 16/180;
                %                 b = 4;
                %                 viento_cal = (corriente_calibrado-b)*(1/a);
                app.viento = app.viento/100;
                app.humedad1 = read(app.plcObj, 'holdingregs', 211, 1);
                %app.humedad1 = 0.25*app.humedad1-50;
                app.humedad2 = read(app.plcObj, 'holdingregs', 213, 1);
                %app.humedad2 = 0.25*app.humedad2-50;
                %temperaturas
                app.temp1 = read(app.plcObj, 'holdingregs', 212, 1);
                app.temp1 = app.temp1*0.1;
                app.temp2 = read(app.plcObj, 'holdingregs', 214, 1);
                app.temp2 = app.temp2*0.1;

                app.radiacion = read(app.plcObj, 'holdingregs', 206, 1);

                app.carga1 = read(app.plcObj, 'holdingregs', 201, 1);
                a=20/65535;
                b=10-a*32767;
                app.carga1 = a*app.carga1+b;
                app.carga1 = (app.carga1*1000)/0.024;

                app.carga2= read(app.plcObj, 'holdingregs', 202, 1);
                app.carga2 = a*app.carga2+b;
                app.carga2 = (app.carga2*1000)/0.024;

                app.carga3 = read(app.plcObj, 'holdingregs', 203, 1);
                app.carga3 = a*app.carga3+b;
                app.carga3 = (app.carga3*1000)/0.024;

                app.carga4 = read(app.plcObj, 'holdingregs', 204, 1);
                app.carga4 = a*app.carga4+b;
                app.carga4 = (app.carga4*1000)/0.024;

                app.evState = read(app.plcObj, 'coils', 128, 1);
                if app.evState(1)
                    app.Est_riegoLamp.Color = 'green';
                else
                    app.Est_riegoLamp.Color = [0.6 0.6 0.6]; % gris
                end
                app.lampStates_1 = read(app.plcObj, 'coils', 113,1);
                app.lampStates_2 = read(app.plcObj, 'coils', 114,1); 
                app.lampStates_3 = read(app.plcObj, 'coils', 115,1); 
                app.lampStates_4 = read(app.plcObj, 'coils', 116,1); % Lee 4 coils desde la dirección 0
                % Actualiza el color de las lámparas según el valor leído

                if app.lampStates_1 
                    app.Lamp1.Color = 'green';
                else
                    app.Lamp1.Color = [0.6 0.6 0.6];
                end

                if app.lampStates_2
                    app.Lamp2.Color = 'green';
                else
                    app.Lamp2.Color = [0.6 0.6 0.6];
                end
                if app.lampStates_3
                    app.Lamp3.Color = 'green';
                else
                    app.Lamp3.Color = [0.6 0.6 0.6];
                end
                if app.lampStates_4
                    app.Lamp4.Color = 'green';
                else
                    app.Lamp4.Color = [0.6 0.6 0.6];
                end

                %                 % actualizar la hora
                %                 app.DateEditField.Value = string(app.currentDate);
                %
                %
                %                 % Actualiza campos en la interfaz:
                %                 app.Humedad1EditField.Value = app.humedad1;
                %                 app.Humedad2EditField.Value = app.humedad2;
                %                 app.RadiacionEditField.Value = app.radiacion;
                %                 app.VientoEditField.Value = app.viento;
                %                 app.VisualizadorGauge.Value = app.viento;
                %                 app.PesoglobalEditField.Value = (app.carga1 + app.carga2 + app.carga3 + app.carga4)/4;
                %                 app.Temperatura1EditField.Value = app.temp1;
                %                 app.Temperatura2EditField.Value = app.temp2;
                %                 app.lim_sup = app.LimitesuperiorEditField.Value;
                %                 app.lim_inf = app.LimiteinferiorEditField.Value;
                %
                %                 % Actualizar buffers de gráfico
                %                 tiempo_horas = max(app.TimeEditField.Value, 0.003);
                %                 tiempo_segundos = tiempo_horas * 3600;
                %                 %disp("Antes: " + length(app.bufferHumedad1) + "," + length(app.bufferTime))
                %                 if tiempo_segundos > length(app.bufferHumedad1)
                %                     app.bufferHumedad1 = [app.bufferHumedad1, app.humedad1];
                %                     app.bufferHumedad2 = [app.bufferHumedad2, app.humedad2];
                %                     app.bufferEV = [app.bufferEV, double(app.evState)];
                %                     app.bufferTime     = [app.bufferTime, now];
                %                     app.bufferLimSup = [app.bufferLimSup, app.lim_sup];
                %                     app.bufferLimInf = [app.bufferLimInf, app.lim_inf];
                %                 elseif tiempo_segundos == length(app.bufferHumedad1)
                %                     app.bufferHumedad1 = [app.bufferHumedad1(2:end), app.humedad1];
                %                     app.bufferHumedad2 = [app.bufferHumedad2(2:end), app.humedad2];
                %                     app.bufferEV = [app.bufferEV(2:end), double(app.evState)];
                %                     app.bufferTime     = [app.bufferTime(2:end), now];
                %                     app.bufferLimSup = [app.bufferLimSup(2:end), app.lim_sup];
                %                     app.bufferLimInf = [app.bufferLimInf(2:end), app.lim_inf];
                %                 else
                %                     app.bufferHumedad1 = [app.bufferHumedad1, app.humedad1];
                %                     app.bufferHumedad1 = app.bufferHumedad1((end-tiempo_segundos):end);
                %
                %                     app.bufferHumedad2 = [app.bufferHumedad2, app.humedad2];
                %                     app.bufferHumedad2 = app.bufferHumedad2((end-tiempo_segundos):end);
                %
                %                     app.bufferEV = [app.bufferEV, double(app.evState)];
                %                     app.bufferEV = app.bufferEV((end-tiempo_segundos):end);
                %
                %                     app.bufferTime     = [app.bufferTime, now];
                %                     app.bufferTime     = app.bufferTime((end-tiempo_segundos):end);
                %
                %                     app.bufferLimSup = [app.bufferLimSup, app.lim_sup];
                %                     app.bufferLimSup = app.bufferLimSup((end-tiempo_segundos):end);
                %
                %                     app.bufferLimInf = [app.bufferLimInf, app.lim_inf];
                %                     app.bufferLimInf = app.bufferLimInf((end-tiempo_segundos):end);
                %
                %                 end
                %                 disp("Despues: " + length(app.bufferHumedad1) + "," + length(app.bufferTime))
                %                 % Actualizar gráfico
                %                 % Convertir a datetime
                %                 t = datetime(app.bufferTime, 'ConvertFrom', 'datenum');
                %
                %                 % ---------- Gráfico superior: Humedades ----------
                %                 hold(app.UIAxes_Humedades, 'on');
                %                 app.UIAxes_Humedades.YLim = [280 380];
                %                 h1 = plot(app.UIAxes_Humedades, t, app.bufferHumedad1, '.b', 'LineWidth', 2);
                %                 h2 = plot(app.UIAxes_Humedades, t, app.bufferHumedad2, '.r', 'LineWidth', 2);
                %
                %                 leyendas = {'H1', 'H2'};
                %                 graficos = [h1, h2];
                %
                %                 if ~strcmp(app.ControladorDropDown.Value, 'Nada')
                %                     h3 = plot(app.UIAxes_Humedades, t, app.bufferLimSup, '--k');
                %                     h4 = plot(app.UIAxes_Humedades, t, app.bufferLimInf, '--k');
                %                     graficos = [graficos, h3, h4];
                %                     leyendas = [leyendas, 'Lim Sup', 'Lim Inf'];
                %                 end
                %
                %                 hold(app.UIAxes_Humedades, 'off');
                %                 legend(app.UIAxes_Humedades, graficos, leyendas, 'Location', 'best');
                %                 title(app.UIAxes_Humedades, 'Humedades');
                %                 ylabel(app.UIAxes_Humedades, '%');
                %                 grid(app.UIAxes_Humedades, 'on');
                %
                %                 % Ajustar márgenes automáticos
                %                 app.UIAxes_Humedades.XLim = [min(t), max(t)];
                %                 app.UIAxes_Humedades.XLimMode = 'manual';
                %
                %                 % ---------- Gráfico inferior: EV ----------
                %                 stairs(app.UIAxes_EV, t, app.bufferEV, '-g', 'LineWidth', 2);
                %                 ylabel(app.UIAxes_EV, 'EV');
                %                 title(app.UIAxes_EV, 'Estado de la electroválvula');
                %                 app.UIAxes_EV.YLim = [-0.2, 1.2];
                %                 app.UIAxes_EV.XLim = [min(t), max(t)];
                %                 app.UIAxes_EV.XLimMode = 'manual';
                %                 grid(app.UIAxes_EV, 'on');

                %% Actualizar la hora
                app.DateEditField.Value = string(app.currentDate);

                % Actualiza campos en la interfaz:
                app.Humedad1EditField.Value = app.humedad1;
                app.Humedad2EditField.Value = app.humedad2;
                app.RadiacionEditField.Value = app.radiacion;
                app.VientoEditField.Value = app.viento;
                app.VisualizadorGauge.Value = app.viento;
                app.PesoglobalEditField.Value = (app.carga1 + app.carga2 + app.carga3 + app.carga4)/4;
                app.Temperatura1EditField.Value = app.temp1;
                app.Temperatura2EditField.Value = app.temp2;
                app.lim_sup = app.LimitesuperiorEditField.Value;
                app.lim_inf = app.LimiteinferiorEditField.Value;

                % Obtener tiempo actual y convertir a datenum (para animatedline)
                t_now = datetime('now');
                x_time = datenum(t_now);  % convierte datetime a double

                % Añadir puntos a animatedline (si el tiempo es válido)
                if isfinite(x_time)
                    addpoints(app.h1, x_time, double(app.humedad1));
                    addpoints(app.h2, x_time, double(app.humedad2));
                    addpoints(app.hLimSup, x_time, double(app.lim_sup));
                    addpoints(app.hLimInf, x_time, double(app.lim_inf));
                    addpoints(app.hEV, x_time, double(app.evState));
                end

                % Obtener datos actuales del gráfico (desde animatedline)
                [xdata_h, ~] = getpoints(app.h1);  % o h2, da igual
                if numel(xdata_h) >= 2
                    % Calcular ventana visible según valor ingresado
                    tiempo_horas = max(app.TimeEditField.Value, 0.01);
                    tiempo_segundos = tiempo_horas * 3600;
                    % Obtener el rango de datos dentro del intervalo deseado
                    t_lim_inf = xdata_h(end) - tiempo_segundos / 86400;
                    idx_visibles = xdata_h >= t_lim_inf;

                    % Si hay suficientes datos en el rango solicitado
                    if any(idx_visibles)
                        xmin = min(xdata_h(idx_visibles));
                        xmax = max(xdata_h(idx_visibles));
                    else
                        xmin = xdata_h(end);
                        xmax = xdata_h(end);
                    end

                    % Asignar límites con protección
                    if isfinite(xmin) && isfinite(xmax) && xmax > xmin
                        app.UIAxes_Humedades.XLim = [xmin, xmax];
                        app.UIAxes_EV.XLim = [xmin, xmax];
                    end


                    % Asignar límites al eje X si son válidos
                    if isfinite(xmin) && isfinite(xmax) && xmax > xmin
                        app.UIAxes_Humedades.XLim = [xmin, xmax];
                        app.UIAxes_EV.XLim = [xmin, xmax];
                    end
                end

                % Formato de eje X: fecha completa con hora
                datetick(app.UIAxes_Humedades, 'x', 'yyyy-mm-dd HH:MM', 'keeplimits');
                datetick(app.UIAxes_EV, 'x', 'yyyy-mm-dd HH:MM', 'keeplimits');


                % Fijar límites y etiquetas Y
                app.UIAxes_Humedades.YLim = [280 400];
                app.UIAxes_EV.YLim = [-0.2, 1.2];
                app.UIAxes_EV.YLabel.String = 'EV';
                app.UIAxes_Humedades.YLabel.String = '%';

                % Mostrar fecha en el título de cada gráfico
                %fecha_actual = datestr(t_now, 'yyyy-mm-dd');
                app.UIAxes_Humedades.Title.String = ['Humedades'];
                app.UIAxes_EV.Title.String = ['Estado de la electroválvula'];

                % Renderizado eficiente
                drawnow limitrate;


            catch ME
                app.logEvento(['Error leyendo sensores: ', ME.message]);
            end
        end


        function conectarPLC(app)
            try
                % Crear objeto Modbus
                app.plcObj = modbus('tcpip', app.IPEditField.Value, 502);
                app.EstadoPLCLamp.Color = 'green';
                app.logEvento('Conexión con PLC establecida correctamente.');
            catch ME
                app.EstadoPLCLamp.Color = 'red';
                app.logEvento(['Error al conectar con PLC: ', ME.message]);
            end
            app.FechainitialEditField.Value = "yyyy-mm-dd HH:MM:SS";
            app.FechafinalEditField.Value = "yyyy-mm-dd HH:MM:SS";
        end

        function guardarRegistro(app)
            try
                fila = { ...
                    app.lampStates_1, app.lampStates_2, app.lampStates_3, app.lampStates_4, app.evState, app.volt_viento, app.carga1, app.carga2, app.carga3, app.carga4, app.viento, app.radiacion, app.humedad1, app.humedad2, app.temp1, app.temp2, app.currentDate ...
                    };

                % Guardar fila en CSV
                writecell(fila, app.archivoCSV, 'WriteMode', 'append');

                % Guardar también en variable interna (opcional)
                app.registroDatos = [app.registroDatos; fila];

            catch ME
                app.logEvento(['Error al guardar registro: ', ME.message]);
            end
        end


    function controlador(app)
        %% Controlador de riego
        control_manual = read(app.plcObj, 'coils', 119, 1);
        if control_manual == 0
            controlador = app.ControladorDropDown.Value;
            if strcmp(controlador,'Histeresis')
                if app.humedad1<app.lim_inf
                    if ~app.evState
                        write(app.plcObj, 'coils', 134, 1);
                        write(app.plcObj, 'coils', 131, 0);
                        app.logEvento(['Se ha iniciado el riego ']);
                    end
                elseif app.humedad1>app.lim_sup
                    if app.evState
                        write(app.plcObj, 'coils', 134, 0);
                        write(app.plcObj, 'coils', 131, 1);
                        write(app.plcObj, 'coils', 131, 0);
                        app.logEvento(['Se ha apagado el riego ']);
                    end
                end

            end
        end
    end
        
    function apagar_todo(app)
        %permite porner todo los sensores/actuadores en 0 y todo en modo
        %manual
        accionLuz1(app,'Off');
        accionLuz2(app,'Off');
        accionLuz3(app,'Off');
        accionLuz4(app,'Off');
        accion_CViento(app, 0);
        accionEV(app, 'Off');
        app.Lamp_1Switch.Value = 'Off';
        app.Lamp_1Switch_2.Value = 'Off';
        app.Lamp_1Switch_5.Value = 'Off';
        app.Lamp_1Switch_4.Value = 'Off';
        app.RiegoSwitch_2.Value = 'Off';
        app.EntradaSlider.Value = 0;

        %Lamp_1SwitchValueChanged(app, event);


    end  
        
    end
    
    methods (Access = public)
        
        
        function accionLuz1(app, value)
            if strcmp(value,'On')
                write(app.plcObj,"coils",123, 1);
            else
                write(app.plcObj,"coils",123, 0);
            end
            app.logEvento(['Modo de Lampara 1 ha cambiado a: ', value]);
        end
        
        function accionLuz2(app, value)
             if strcmp(value,'On')
                write(app.plcObj,"coils",124, 1);
            else
                write(app.plcObj,"coils",124, 0);
            end
            app.logEvento(['Modo de Lampara 2 ha cambiado a: ', value]);
            
            
        end
        
        function accionLuz3(app, value)
            if strcmp(value,'On')
                write(app.plcObj, 'coils', 132, 1);
            else
                write(app.plcObj, 'coils', 132, 0);
            end
            app.logEvento(['Modo de Lampara 3 a cambiado a: ', value]);
            
        end    


        function accionLuz4(app, value)
            if strcmp(value,'On')
                write(app.plcObj, 'coils', 126, 1);
            else
                write(app.plcObj, 'coils', 126, 0);
            end
            app.logEvento(['Modo de Lampara 4 a cambiado a: ', value]);
        end
        
        function accion_CViento(app, valor)
            app.VisualizadorGauge.Value = valor;
            app.logEvento(['Ventilador ajustado a ', num2str(valor), ' %']);
            valor = valor*4950/100;
            valor = round(valor);
            write(app.plcObj,'holdingregs',207,valor)
            
        end
        
        function accionEV(app, modo)
            if strcmp(modo,'On')
                write(app.plcObj, 'coils', 122, 1);
                write(app.plcObj, 'coils', 131, 0);
            else
                write(app.plcObj, 'coils', 122, 0);
                write(app.plcObj, 'coils', 131, 1);
                write(app.plcObj, 'coils', 131, 0);
            end
            app.logEvento(['Estado de la electroválvula a cambiado a: ', modo]);
            
        end
        
        function accionNADA(app, value)
            app.ControladorDropDown.Value = value;
            if strcmp(value,'Nada')
              write(app.plcObj, 'coils', 122, 0);
              write(app.plcObj, 'coils', 131, 1);
              write(app.plcObj, 'coils', 131, 0);
              app.RiegoSwitch_2.Value = 'Off';
            end
            app.logEvento(['Estado de la electroválvula a cambiado a: ', value]);
        end
        
        function guardarDatos(app)
            
        app.sqlquery = sprintf("INSERT INTO medicion (bombilla1, bombilla2, bombilla3, bombilla4, electrovalvula, VoltVentilador, carga1, carga2, carga3, carga4, VelocidadViento, radiacion, humedad1, humedad2, temperatura1, temperatura2, date) VALUES (%d, %d, %d, %d, %d, %2f, %2f, %2f, %2f, %2f, %2f, %d, %2f, %2f, %2f, %2f, '%s')", app.lampStates_1, app.lampStates_2, app.lampStates_3, app.lampStates_4, app.evState, app.volt_viento, app.carga1, app.carga2, app.carga3, app.carga4, app.viento, app.radiacion, app.humedad1, app.humedad2, app.temp1, app.temp2, app.currentDate);
        exec(app.conn, app.sqlquery);  
            
        end
        
        function fechasBDD(app)
       
            app.fecha1 = app.FechainitialEditField.Value;
            app.fecha2 = app.FechafinalEditField.Value;

            if app.fecha1 == "yyyy-mm-dd HH:MM:SS"
                uialert(app.UIFigure, ...
                        ' Elige una fecha initial', ...
                        'Error de formato');
                    return;
            end

             if app.fecha2 == "yyyy-mm-dd HH:MM:SS"
                uialert(app.UIFigure, ...
                        ' Elige una fecha final', ...
                        'Error de formato');
                    return;
            end

            fecha1Str = datestr(app.fecha1, 'yyyy-mm-dd HH:MM:SS');
            fecha2Str = datestr(app.fecha2, 'yyyy-mm-dd HH:MM:SS');
            pattern = '^\d{4}-\d{2}-\d{2} \d{2}:\d{2}:\d{2}$';
        
        
            app.tipo = app.TipodataDropDown.Value;
            match1 = ~isempty(regexp(app.fecha1, pattern, 'once'));
            match2 = ~isempty(regexp(app.fecha2, pattern, 'once'));

                if ~match1 || ~match2
                    uialert(app.UIFigure, ...
                        ' Utilisa este formato para las fechas : yyyy-mm-dd HH:MM:SS', ...
                        'Error de formato');
                    return;
                end


           app.logEvento(['Dato selectionado: ', app.tipo]);

           app.tipo = strtrim(app.TipodataDropDown.Value); 


                 %app.SqlSelectDatos = sprintf("SELECT %s FROM medicion WHERE date BETWEEN '%s' AND '%s';", app.tipo, fecha1Str, fecha2Str);
                 app.SqlSelectDatos = sprintf("SELECT date, %s FROM medicion WHERE date BETWEEN '%s' AND '%s';", app.tipo, fecha1Str, fecha2Str);

                 exec(app.conn, app.SqlSelectDatos);
                 %app.logEvento([app.SqlSelectDatos]);
                 app.logEvento(["Sql request hecha, has selectionado ", app.TipodataDropDown.Value]);


                 datos = fetch(app.conn, app.SqlSelectDatos);
                
                if isempty(datos)
                    uialert(app.UIFigure, 'Aucune donnée trouvée pour l''intervalle spécifié.', 'Pas de données');
                    return;
                end
                    
                    % Converción de la columna date
                    try
                        if iscell(datos.date)
                            app.fechas = datetime(string(datos.date), 'InputFormat', 'yyyy-MM-dd HH:mm:ss');
                        elseif isstring(datos.date)
                            app.fechas = datetime(datos.date, 'InputFormat', 'yyyy-MM-dd HH:mm:ss');
                        elseif isdatetime(datos.date)
                            app.fechas = datos.date;
                        else
                            error('Format non reconnu');
                        end
                    catch
                        uialert(app.UIFigure, 'Erreur de conversion de la colonne date.', 'Erreur');
                        return;
                    end
                    
                    app.valeurs = datos.(app.tipo);
                    
                    % Graficar
                    plot(app.UIAxes2, app.fechas, app.valeurs, '-');
                    title(app.UIAxes2, sprintf('Evolución de %s', app.tipo));
                    xlabel(app.UIAxes2, 'Date');
                    ylabel(app.UIAxes2, app.tipo);
                    grid(app.UIAxes2, 'on');
                    
                    minY = min(app.valeurs);
                    maxY = max(app.valeurs);
                
                    if minY == maxY
                        yMinLim = minY - 1;
                        yMaxLim = maxY + 1;
                    else
                        yMargin = 0.05 * (maxY - minY);  % margen del 5%
                        yMinLim = minY - yMargin;
                        yMaxLim = maxY + yMargin;
                    end
                
                    % Seguridad: asegurar que el límite superior es mayor
                    if yMaxLim <= yMinLim
                        yMaxLim = yMinLim + 1;
                    end
                
                    app.UIAxes2.YLim = [yMinLim, yMaxLim];
                
                    % -------- Limites del eje X (fechas) con margen --------
                    minX = min(app.fechas);
                    maxX = max(app.fechas);
                
                    if minX == maxX
                        app.UIAxes2.XLim = [minX - minutes(1), maxX + minutes(1)];
                    else
                        xMargin = seconds(0.05 * seconds(maxX - minX));  % margen del 5%
                        app.UIAxes2.XLim = [minX - xMargin, maxX + xMargin];
                    end


                end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Inicializa el timer para lectura de sensores cada 1 segundo
            app.timerSensores = timer('ExecutionMode','fixedSpacing','Period',5,...
            'TimerFcn', @(~,~) app.leerSensores());
            start(app.timerSensores);

            %% Inicializa log si quieres
            app.logEventos = "";
            app.LogEventos.Value = app.logEventos;
            app.EstadoPLCLamp.Color = 'red';

            %% Timer para guardado periódico (puedes ajustar el periodo)
            % Crear carpeta si no existe
            directorioApp = fileparts(mfilename('fullpath'));
            carpeta = fullfile(directorioApp, 'Datos');

            if ~exist(carpeta, 'dir')
                mkdir(carpeta);
            end

            % Crear nombre de archivo con marca temporal única
            fechaHora = datestr(now, 'yyyy-mm-dd_HH-MM-SS');
            nombreArchivo = ['registro_' fechaHora '.csv'];

            % Guardar ruta completa del archivo en una propiedad
            app.archivoCSV = fullfile(carpeta, nombreArchivo);

            % Escribir encabezado del CSV
            encabezado = {'bombilla1', 'bombilla2', 'bombilla3', 'bombilla4', 'electrovalvula', 'VoltVentilador', ...
                'carga1', 'carga2', 'carga3', 'carga4', 'VelocidadViento', 'radiacion', 'humedad1', 'humedad2', ...
                ' temperatura1', 'temperatura2', 'date'};

            if ~isfile(app.archivoCSV)
                try
                    writecell(encabezado, app.archivoCSV);
                catch ME
                    app.logEvento(['No se pudo crear el archivo CSV: ', ME.message]);
                end
            end

            app.intervaloGuardado = 5; % O 20, o 60, o el valor que quieras
            app.timerGuardado = timer('ExecutionMode', 'fixedSpacing','Period',...
                app.intervaloGuardado,'TimerFcn', @(~,~) app.guardarRegistro());
            start(app.timerGuardado);

            %% Timer para base de datos
            app.timerBaseDeDatos = timer('ExecutionMode','fixedSpacing','Period',60,...
            'TimerFcn', @(~,~) app.guardarDatos());

            app.conn = database('connexion', 'root', 'Bancada2025!');

            if isopen(app.conn)
                app.logEvento('Connecion ODBC hecha.');
            else
                app.logEvento('Error de la connecion ODBC .');
            end
            start(app.timerBaseDeDatos);

            %% Inicializar líneas animadas para graficar en tiempo real
            app.h1 = animatedline(app.UIAxes_Humedades, 'Color', 'b', 'LineWidth', 2);
            app.h2 = animatedline(app.UIAxes_Humedades, 'Color', 'r', 'LineWidth', 2);
            app.hLimSup = animatedline(app.UIAxes_Humedades, 'Color', 'k', 'LineStyle', '--');
            app.hLimInf = animatedline(app.UIAxes_Humedades, 'Color', 'k', 'LineStyle', '--');
            app.hEV = animatedline(app.UIAxes_EV, 'Color', 'g', 'LineWidth', 2);


        end

        % Button pushed function: ParadaEmergenciaButton
        function ParadaEmergenciaButtonPushed(app, event)
            apagar_todo(app);
      
            % Aquí debe ir la lógica para enviar el comando de parada al PLC vía Modbus.
            % Por ahora solo un ejemplo:
            app.logEvento('Parada de emergencia activada');
            % Aquí pondrías las líneas para apagar salidas (luces, riego, ventilador)
            % y escribir el bit de parada de emergencia en el PLC.
            % Ejemplo (pseudo-código):
            % write(plcObj, 'coil', dir_parada_emerg, 1);
        end

        % Value changed function: LucesSwitch
        function LucesSwitchValueChanged(app, event)
            modo = app.LucesSwitch.Value;
            app.logEvento(['Modo de luces cambiado a: ', modo]);
            % Habilita o deshabilita los controles manuales según modo
            isManual = strcmp(modo,'Manual');
            app.Lamp_1Switch.Enable = isManual;
            app.Lamp_1Switch_2.Enable = isManual;
            app.Lamp_1Switch_5.Enable = isManual;
            app.Lamp_1Switch_4.Enable = isManual;
            if strcmp(modo,'Manual')
                write(app.plcObj,"coils",120,1);
            else
                write(app.plcObj,"coils",120,0);
            end
        end

        % Value changed function: RiegoSwitch
        function RiegoSwitchValueChanged(app, event)
            write(app.plcObj, 'coils', 134, 0);
            write(app.plcObj, 'coils', 131, 1);
            write(app.plcObj, 'coils', 131, 0);
            modo = app.RiegoSwitch.Value;
            app.logEvento(['Modo de riego cambiado a: ', modo]);
            app.RiegoSwitch_2.Enable = strcmp(modo,'Manual');
            if strcmp(modo, "Manual")
                write(app.plcObj,"coils", 133,1);
            else
                write(app.plcObj,"coils", 133,0);
            end
            app.logEvento(['Modo de electroválvula cambiado a: ', modo]);
        end

        % Value changed function: VentiladorSwitch
        function VentiladorSwitchValueChanged(app, event)
            modo = app.VentiladorSwitch.Value;
            app.logEvento(['Modo de ventilador cambiado a: ', modo]);
            app.EntradaSlider.Enable = strcmp(modo,'Manual');
            if strcmp(modo, "Manual")
                write(app.plcObj,"coils", 127,1);
            else
                write(app.plcObj,"coils", 127,0);
            end
        end

        % Value changed function: EntradaSlider
        function EntradaSliderValueChanged(app, event)
            valor = app.EntradaSlider.Value;
            accion_CViento(app, valor);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %% funcion que apaga todas las salidas
            apagar_todo(app);
            
            %% Detener y eliminar el timer si está activo
            if isvalid(app.timerSensores)
                stop(app.timerSensores);
                delete(app.timerSensores);
            end
            %%
            if isvalid(app.timerGuardado)
                stop(app.timerGuardado);
                delete(app.timerGuardado);
            end
            %%
            if ~isempty(app.timer_controlador) && isa(app.timer_controlador, 'timer') && isvalid(app.timer_controlador)
                stop(app.timer_controlador);
                delete(app.timer_controlador);
            end
            %%
            if isvalid(app.timerBaseDeDatos)
                stop(app.timerBaseDeDatos);
                delete(app.timerBaseDeDatos);
            end
            
            % Cerrar la figura de la aplicación
            delete(app.UIFigure);
            delete(app)

        end

        % Button pushed function: ConectarPLCButton
        function ConectarPLCButtonPushed(app, event)
            conectarPLC(app);
        end

        % Value changed function: Lamp_1Switch
        function Lamp_1SwitchValueChanged(app, event)
            value = app.Lamp_1Switch.Value;
            accionLuz1(app,value);
        end

        % Value changed function: Lamp_1Switch_2
        function Lamp_1Switch_2ValueChanged(app, event)
            value = app.Lamp_1Switch_2.Value;
            accionLuz2(app, value);
        end

        % Value changed function: Lamp_1Switch_4
        function Lamp_1Switch_4ValueChanged(app, event)
            value = app.Lamp_1Switch_4.Value;
            accionLuz4(app, value);
        end

        % Value changed function: Lamp_1Switch_5
        function Lamp_1Switch_5ValueChanged(app, event)
           value = app.Lamp_1Switch_5.Value;
           accionLuz3(app,value);

        end

        % Value changed function: RiegoSwitch_2
        function RiegoSwitch_2ValueChanged(app, event)
            modo = app.RiegoSwitch_2.Value;
            accionEV(app,modo);
        end

        % Button pushed function: ExportarCSVButton
        function ExportarCSVButtonPushed(app, event)
            % Selecciona el archivo donde guardar
            [file, path] = uiputfile('humedades.csv', 'Guardar datos de humedades como CSV');
            if isequal(file,0)
                return; % Usuario canceló
            end
            % Convierte los tiempos seriales a strings legibles
            tiempos_legibles = cellstr(datestr(app.bufferTime(:), 'yyyy-mm-dd HH:MM:SS'));
            hum1 = app.bufferHumedad1(:);
            hum2 = app.bufferHumedad2(:);

            % Construye la tabla
            t = table(tiempos_legibles, hum1, hum2, 'VariableNames', {'Tiempo', 'Humedad1', 'Humedad2'});

            % Escribe la tabla en el archivo CSV
            writetable(t, fullfile(path, file));
            uialert(app.UIFigure, 'Datos exportados correctamente a CSV.', 'Exportación exitosa');
        end

        % Button pushed function: GuardarImagenButton
        function GuardarImagenButtonPushed(app, event)
            % Selecciona el archivo donde guardar la imagen
            [file, path] = uiputfile({'*.png';'*.jpg'}, 'Guardar gráfico de humedades como imagen');
            if isequal(file,0)
                return; % Usuario canceló
            end
            fullpath = fullfile(path, file);
            try
                exportgraphics(app.UIAxes_Humedades, fullpath);  % Funciona con uiaxes
                uialert(app.UIFigure, 'Imagen guardada correctamente.', 'Exportación exitosa');
            catch ME
                uialert(app.UIFigure, ['Error al guardar la imagen: ' ME.message], 'Error');
            end
        end

        % Value changed function: LimitesuperiorEditField
        function LimitesuperiorEditFieldValueChanged(app, event)
            value = app.LimitesuperiorEditField.Value;
            app.lim_sup = value;
        end

        % Value changed function: LimiteinferiorEditField
        function LimiteinferiorEditFieldValueChanged(app, event)
            value = app.LimiteinferiorEditField.Value;
            app.lim_inf = value;
        end

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)
            % Inicializa el timer para lectura de sensores cada 1 segundo
            app.timer_controlador = timer('ExecutionMode','fixedSpacing','Period',app.TsEditField.Value,...
            'TimerFcn', @(~,~) app.controlador());
            start(app.timer_controlador);
        end

        % Value changed function: ControladorDropDown
        function ControladorDropDownValueChanged(app, event)
            value = app.ControladorDropDown.Value;
            accionNADA(app, value);

            
        end

        % Value changed function: TipodataDropDown
        function TipodataDropDownValueChanged(app, event)
            selectedType = app.TipodataDropDown.Value; % Obtiene el valor seleccionado
            title(app.UIAxes2, selectedType);           % Actualiza el título del gráfico
            
        end

        % Button pushed function: SelectionarButton
        function SelectionarButtonPushed(app, event)
            fechasBDD(app);
            app.logEvento('Datos y fechas bien selctionado');
        end

        % Button pushed function: ExportarCSVButton_2
        function ExportarCSVButton_2Pushed(app, event)
        % Verificar que hay datos disponibles
        % pensar a abrir el csv con el bloque de notas para ver las valores
       if isempty(app.fechas) || isempty(app.valeurs)
            uialert(app.UIFigure, ...
                'No hay datos para exportar. Por favor, primero consulta una gráfica.', ...
                'Exportación fallida');
            return;
        end
        
        if numel(app.fechas) ~= numel(app.valeurs)
            uialert(app.UIFigure, ...
                'Los datos de fechas y valores no tienen el mismo tamaño.', ...
                'Error de datos');
            return;
        end
        
        % Crear tabla con los datos
        tablaExport = table(app.fechas(:), app.valeurs(:), 'VariableNames', {'Fecha', app.tipo});
        
        % Diálogo para elegir ubicación y nombre del archivo
        [archivo, ruta] = uiputfile('datos_exportados.csv', 'Guardar como CSV');
        if isequal(archivo, 0) || isequal(ruta, 0)
            app.logEvento('Exportación cancelada por el usuario.');
            return;
        end
        
        archivoCompleto = fullfile(ruta, archivo);
        
        % Intentar guardar el archivo
        try
            writetable(tablaExport, archivoCompleto);
            app.logEvento(['Datos exportados a CSV: ', archivoCompleto]);
            uialert(app.UIFigure, 'Exportación completada correctamente.', 'CSV guardado');
        catch e
            uialert(app.UIFigure, ['Error al guardar el archivo: ', e.message], 'Error');
        end


        end

        % Button pushed function: GuardarImagenButton_2
        function GuardarImagenButton_2Pushed(app, event)
            % Abre una ventana para seleccionar dónde guardar la imagen
            [file, path] = uiputfile({'*.png';'*.jpg'}, 'Guardar gráfico como imagen');
            
            % Verifica si el usuario canceló la operación
            if isequal(file, 0)
                return; % Usuario canceló, salir de la función
            end
            
            % Construye la ruta completa del archivo
            fullpath = fullfile(path, file);
            
            % Intenta exportar el gráfico y muestra un mensaje al usuario
            try
                exportgraphics(app.UIAxes2, fullpath);
                uialert(app.UIFigure, 'Imagen guardada correctamente.', 'Exportación exitosa');
            catch ME
                uialert(app.UIFigure, ['Error al guardar la imagen: ' ME.message], 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'ods-banner-labs.png');
            app.MododefuncionamientoPanel = uipanel(app.UIFigure);
            app.MododefuncionamientoPanel.Title = 'Modo de funcionamiento';
            app.LucesSwitchLabel = uilabel(app.MododefuncionamientoPanel);
            app.LucesSwitchLabel.Text = 'Luces';
            app.LucesSwitch = uiswitch(app.MododefuncionamientoPanel, 'toggle');
            app.LucesSwitch.Items = {'Manual', 'Auto'};
            app.LucesSwitch.ValueChangedFcn = createCallbackFcn(app, @LucesSwitchValueChanged, true);
            app.LucesSwitch.Value = 'Manual';
            app.RiegoSwitch_2Label = uilabel(app.MododefuncionamientoPanel);
            app.RiegoSwitch_2Label.Text = 'Riego';
            app.RiegoSwitch = uiswitch(app.MododefuncionamientoPanel, 'toggle');
            app.RiegoSwitch.Items = {'Manual', 'Auto'};
            app.RiegoSwitch.ValueChangedFcn = createCallbackFcn(app, @RiegoSwitchValueChanged, true);
            app.RiegoSwitch.Value = 'Manual';
            app.VentiladorSwitchLabel = uilabel(app.MododefuncionamientoPanel);
            app.VentiladorSwitchLabel.Text = 'Ventilador';
            app.VentiladorSwitch = uiswitch(app.MododefuncionamientoPanel, 'toggle');
            app.VentiladorSwitch.Items = {'Manual', 'Auto'};
            app.VentiladorSwitch.ValueChangedFcn = createCallbackFcn(app, @VentiladorSwitchValueChanged, true);
            app.VentiladorSwitch.Value = 'Manual';
            app.LucesPanel = uipanel(app.UIFigure);
            app.LucesPanel.Title = 'Luces';
            app.Lamp1Label = uilabel(app.LucesPanel);
            app.Lamp1Label.Text = 'Lamp 1';
            app.Lamp1 = uilamp(app.LucesPanel);
            app.Lamp2Label = uilabel(app.LucesPanel);
            app.Lamp2Label.Text = 'Lamp 2';
            app.Lamp2 = uilamp(app.LucesPanel);
            app.Lamp3Label = uilabel(app.LucesPanel);
            app.Lamp3Label.Text = 'Lamp 3';
            app.Lamp3 = uilamp(app.LucesPanel);
            app.Lamp4Label = uilabel(app.LucesPanel);
            app.Lamp4Label.Text = 'Lamp 4';
            app.Lamp4 = uilamp(app.LucesPanel);
            app.Lamp_1Switch = uiswitch(app.LucesPanel, 'toggle');
            app.Lamp_1Switch.ValueChangedFcn = createCallbackFcn(app, @Lamp_1SwitchValueChanged, true);
            app.Lamp_1Switch_2 = uiswitch(app.LucesPanel, 'toggle');
            app.Lamp_1Switch_2.ValueChangedFcn = createCallbackFcn(app, @Lamp_1Switch_2ValueChanged, true);
            app.Lamp_1Switch_4 = uiswitch(app.LucesPanel, 'toggle');
            app.Lamp_1Switch_4.ValueChangedFcn = createCallbackFcn(app, @Lamp_1Switch_4ValueChanged, true);
            app.Lamp_1Switch_5 = uiswitch(app.LucesPanel, 'toggle');
            app.Lamp_1Switch_5.ValueChangedFcn = createCallbackFcn(app, @Lamp_1Switch_5ValueChanged, true);
            app.PLCPanel = uipanel(app.UIFigure);
            app.PLCPanel.Title = 'PLC';
            app.EstadoPLCLampLabel = uilabel(app.PLCPanel);
            app.EstadoPLCLampLabel.Text = 'Estado PLC';
            app.EstadoPLCLamp = uilamp(app.PLCPanel);
            app.IPEditFieldLabel = uilabel(app.PLCPanel);
            app.IPEditFieldLabel.Text = 'IP';
            app.IPEditField = uieditfield(app.PLCPanel, 'text');
            app.IPEditField.Value = '10.30.236.8';
            app.ConectarPLCButton = uibutton(app.PLCPanel, 'push');
            app.ConectarPLCButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarPLCButtonPushed, true);
            app.ConectarPLCButton.Text = 'Conectar PLC';
            app.VentiladorPanel = uipanel(app.UIFigure);
            app.VentiladorPanel.Title = 'Ventilador';
            app.VisualizadorGaugeLabel = uilabel(app.VentiladorPanel);
            app.VisualizadorGaugeLabel.Text = 'Visualizador';
            app.VisualizadorGauge = uigauge(app.VentiladorPanel, 'semicircular');
            app.VisualizadorGauge.Limits = [0 30];
            app.EntradaSliderLabel = uilabel(app.VentiladorPanel);
            app.EntradaSliderLabel.Text = 'Entrada';
            app.EntradaSlider = uislider(app.VentiladorPanel);
            app.EntradaSlider.ValueChangedFcn = createCallbackFcn(app, @EntradaSliderValueChanged, true);
            app.MedicionesPanel = uipanel(app.UIFigure);
            app.MedicionesPanel.Title = 'Mediciones';
            app.Humedad1EditFieldLabel = uilabel(app.MedicionesPanel);
            app.Humedad1EditFieldLabel.Text = 'Humedad 1';
            app.Humedad1EditField = uieditfield(app.MedicionesPanel, 'numeric');
            app.Humedad2EditFieldLabel = uilabel(app.MedicionesPanel);
            app.Humedad2EditFieldLabel.Text = 'Humedad 2';
            app.Humedad2EditField = uieditfield(app.MedicionesPanel, 'numeric');
            app.RadiacionEditFieldLabel = uilabel(app.MedicionesPanel);
            app.RadiacionEditFieldLabel.Text = 'Radiacion';
            app.RadiacionEditField = uieditfield(app.MedicionesPanel, 'numeric');
            app.VientoEditFieldLabel = uilabel(app.MedicionesPanel);
            app.VientoEditFieldLabel.Text = 'Viento';
            app.VientoEditField = uieditfield(app.MedicionesPanel, 'numeric');
            app.Temperatura2EditFieldLabel = uilabel(app.MedicionesPanel);
            app.Temperatura2EditFieldLabel.Text = 'Temperatura 2';
            app.Temperatura2EditField = uieditfield(app.MedicionesPanel, 'numeric');
            app.PesoglobalEditFieldLabel = uilabel(app.MedicionesPanel);
            app.PesoglobalEditFieldLabel.Text = 'Peso global';
            app.PesoglobalEditField = uieditfield(app.MedicionesPanel, 'numeric');
            app.Temperatura1EditFieldLabel = uilabel(app.MedicionesPanel);
            app.Temperatura1EditFieldLabel.Text = 'Temperatura 1';
            app.Temperatura1EditField = uieditfield(app.MedicionesPanel, 'numeric');
            app.Label = uilabel(app.MedicionesPanel);
            app.Label.Text = '%';
            app.Label_2 = uilabel(app.MedicionesPanel);
            app.Label_2.Text = '%';
            app.Label_3 = uilabel(app.MedicionesPanel);
            app.Label_3.Text = '%';
            app.CLabel = uilabel(app.MedicionesPanel);
            app.CLabel.Text = 'ºC';
            app.CLabel_2 = uilabel(app.MedicionesPanel);
            app.CLabel_2.Text = 'ºC';
            app.kmhLabel = uilabel(app.MedicionesPanel);
            app.kmhLabel.Text = 'km/h';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HumedadesTab = uitab(app.TabGroup);
            app.HumedadesTab.Title = 'Humedades';
            app.UIAxes_EV = uiaxes(app.HumedadesTab);
            title(app.UIAxes_EV, 'Humedades')
            xlabel(app.UIAxes_EV, 'Time')
            ylabel(app.UIAxes_EV, '%')
            zlabel(app.UIAxes_EV, 'Z')
            app.UIAxes_EV.YLim = [0 100];
            app.UIAxes_Humedades = uiaxes(app.HumedadesTab);
            title(app.UIAxes_Humedades, 'Humedades')
            xlabel(app.UIAxes_Humedades, 'Time')
            ylabel(app.UIAxes_Humedades, '%')
            zlabel(app.UIAxes_Humedades, 'Z')
            app.UIAxes_Humedades.YLim = [0 100];
            app.ExportarCSVButton = uibutton(app.HumedadesTab, 'push');
            app.ExportarCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarCSVButtonPushed, true);
            app.ExportarCSVButton.Text = 'ExportarCSV';
            app.GuardarImagenButton = uibutton(app.HumedadesTab, 'push');
            app.GuardarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarImagenButtonPushed, true);
            app.GuardarImagenButton.Text = 'GuardarImagen';
            app.TimeEditFieldLabel = uilabel(app.HumedadesTab);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.HumedadesTab, 'numeric');
            app.TimeEditField.Value = 0.01;
            app.horasLabel = uilabel(app.HumedadesTab);
            app.horasLabel.Text = 'horas';
            app.ControladorTab = uitab(app.TabGroup);
            app.ControladorTab.Title = 'Controlador';
            app.ControladorDropDownLabel = uilabel(app.ControladorTab);
            app.ControladorDropDownLabel.Text = 'Controlador';
            app.ControladorDropDown = uidropdown(app.ControladorTab);
            app.ControladorDropDown.Items = {'Nada', 'Histeresis', 'MPC', 'AMPC'};
            app.ControladorDropDown.ValueChangedFcn = createCallbackFcn(app, @ControladorDropDownValueChanged, true);
            app.ControladorDropDown.Value = 'Nada';
            app.LimitesuperiorEditFieldLabel = uilabel(app.ControladorTab);
            app.LimitesuperiorEditFieldLabel.Text = 'Limite superior';
            app.LimitesuperiorEditField = uieditfield(app.ControladorTab, 'numeric');
            app.LimitesuperiorEditField.ValueChangedFcn = createCallbackFcn(app, @LimitesuperiorEditFieldValueChanged, true);
            app.LimitesuperiorEditField.Value = 45;
            app.LimiteinferiorEditFieldLabel = uilabel(app.ControladorTab);
            app.LimiteinferiorEditFieldLabel.Text = 'Limite inferior';
            app.LimiteinferiorEditField = uieditfield(app.ControladorTab, 'numeric');
            app.LimiteinferiorEditField.ValueChangedFcn = createCallbackFcn(app, @LimiteinferiorEditFieldValueChanged, true);
            app.LimiteinferiorEditField.Value = 30;
            app.InicioButton = uibutton(app.ControladorTab, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Text = 'Inicio';
            app.TsEditFieldLabel = uilabel(app.ControladorTab);
            app.TsEditFieldLabel.Text = 'Ts';
            app.TsEditField = uieditfield(app.ControladorTab, 'numeric');
            app.TsEditField.Value = 1;
            app.SelectDatosTab = uitab(app.TabGroup);
            app.SelectDatosTab.Title = 'Select Datos';
            app.UIAxes2 = uiaxes(app.SelectDatosTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'Date')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SelectionarButton = uibutton(app.SelectDatosTab, 'push');
            app.SelectionarButton.ButtonPushedFcn = createCallbackFcn(app, @SelectionarButtonPushed, true);
            app.SelectionarButton.Text = 'Selectionar';
            app.TipodataDropDownLabel = uilabel(app.SelectDatosTab);
            app.TipodataDropDownLabel.Text = 'Tipo data';
            app.TipodataDropDown = uidropdown(app.SelectDatosTab);
            app.TipodataDropDown.Items = {'bombilla1  ', 'bombilla2  ', 'bombilla3 ', 'bombilla4 ', 'electrovalvula ', 'VoltVentilador ', 'carga1 ', 'carga2', 'carga3  ', 'carga4 ', 'VelocidadViento ', 'radiacion  ', 'humedad1  ', 'humedad2  ', 'temperatura1 ', 'temperatura2 '};
            app.TipodataDropDown.ValueChangedFcn = createCallbackFcn(app, @TipodataDropDownValueChanged, true);
            app.TipodataDropDown.Value = 'bombilla1  ';
            app.FechainitialEditFieldLabel = uilabel(app.SelectDatosTab);
            app.FechainitialEditFieldLabel.Text = 'Fecha initial';
            app.FechainitialEditField = uieditfield(app.SelectDatosTab, 'text');
            app.FechafinalEditFieldLabel = uilabel(app.SelectDatosTab);
            app.FechafinalEditFieldLabel.Text = 'Fecha final';
            app.FechafinalEditField = uieditfield(app.SelectDatosTab, 'text');
            app.ExportarCSVButton_2 = uibutton(app.SelectDatosTab, 'push');
            app.ExportarCSVButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportarCSVButton_2Pushed, true);
            app.ExportarCSVButton_2.Text = 'Exportar CSV';
            app.GuardarImagenButton_2 = uibutton(app.SelectDatosTab, 'push');
            app.GuardarImagenButton_2.ButtonPushedFcn = createCallbackFcn(app, @GuardarImagenButton_2Pushed, true);
            app.GuardarImagenButton_2.Text = 'GuardarImagen';
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Title = 'Log';
            app.logEventosLabel = uilabel(app.LogTab);
            app.logEventosLabel.Text = 'logEventos';
            app.LogEventos = uitextarea(app.LogTab);
            app.ParadaEmergenciaButton = uibutton(app.UIFigure, 'push');
            app.ParadaEmergenciaButton.ButtonPushedFcn = createCallbackFcn(app, @ParadaEmergenciaButtonPushed, true);
            app.ParadaEmergenciaButton.Text = 'Parada Emergencia';
            app.RiegoSwitch_2Label_2 = uilabel(app.UIFigure);
            app.RiegoSwitch_2Label_2.Text = 'Riego';
            app.RiegoSwitch_2 = uiswitch(app.UIFigure, 'toggle');
            app.RiegoSwitch_2.ValueChangedFcn = createCallbackFcn(app, @RiegoSwitch_2ValueChanged, true);
            app.Est_riegoLampLabel = uilabel(app.UIFigure);
            app.Est_riegoLampLabel.Text = 'Est_riego';
            app.Est_riegoLamp = uilamp(app.UIFigure);
            app.DateEditFieldLabel = uilabel(app.UIFigure);
            app.DateEditFieldLabel.Text = 'Date';
            app.DateEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_bancada

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end